classdef FinalProjectApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ReportC_TextArea    matlab.ui.control.TextArea
        TextArea_3Label     matlab.ui.control.Label
        ReportB_TextArea    matlab.ui.control.TextArea
        TextArea_2Label     matlab.ui.control.Label
        ReportA_TextArea    matlab.ui.control.TextArea
        TextAreaLabel       matlab.ui.control.Label
        ProcessButton       matlab.ui.control.Button
        ImageC_After_Axes   matlab.ui.control.UIAxes
        ImageC_Before_Axes  matlab.ui.control.UIAxes
        ImageB_After_Axes   matlab.ui.control.UIAxes
        ImageB_Before_Axes  matlab.ui.control.UIAxes
        ImageA_After_Axes   matlab.ui.control.UIAxes
        ImageA_Before_Axes  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            % 1. 開啟檔案選擇對話框，從 config 讀取支援的格式
            [files, path] = uigetfile(env.config.SUPPORTED_IMAGE_FORMATS, ...
                                  '請選擇三張圖片檔案', ...
                                  'MultiSelect', 'on');
            
            % 檢查使用者是否正確選擇檔案
            if ~iscell(files) || numel(files) ~= 3
                uialert(app.UIFigure, '請務必選擇剛好 3 張圖片！', '選擇錯誤');
                return;
            end
            
            % 準備好要操作的元件名稱列表 (這部分屬於流程控制，保留在此)
            before_axes = {app.ImageA_Before_Axes, app.ImageB_Before_Axes, app.ImageC_Before_Axes};
            after_axes = {app.ImageA_After_Axes, app.ImageB_After_Axes, app.ImageC_After_Axes};
            report_areas = {app.ReportA_TextArea, app.ReportB_TextArea, app.ReportC_TextArea};
            output_labels = {'A', 'B', 'C'};
        
            % 進入忙碌狀態
            app.UIFigure.Pointer = 'watch';
            drawnow;
            
            try
                % 2. 依序處理每一張圖片
                for i = 1:3
                    image_path = fullfile(path, files{i});
                    
                    % 3. 顯示原始圖片
                    original_image = imread(image_path);
                    imshow(original_image, 'Parent', before_axes{i});
                    title(before_axes{i}, '處理前');
                    
                    % 4. 呼叫核心處理函式 (它會自己去讀取 env.tuning 中的參數)
                    [final_image, ~, report] = functions.process_image(image_path);
                    
                    % 5. 顯示處理後的圖片
                    imshow(final_image, 'Parent', after_axes{i});
                    title(after_axes{i}, '處理後');
                    
                    % 6. 格式化並顯示診斷報告
                    lighting_status = report.lighting;
                    noise_status = report.noise;
                    if report.blur, blur_status = '是'; else, blur_status = '否'; end
                    
                    steps = report.applied_steps;
                    steps_str = '';
                    for k = 1:length(steps)
                        steps_str = [steps_str, sprintf('- %s\n', steps{k})];
                    end
                    
                    report_text = sprintf([...
                        '--- 影像診斷 ---\n' ...
                        '光線問題: %s\n' ...
                        '雜訊問題: %s\n' ...
                        '是否模糊: %s\n\n' ...
                        '--- 套用處理步驟 ---\n%s'], ...
                        lighting_status, noise_status, blur_status, steps_str);
                    report_areas{i}.Value = report_text;
                    if ~exist(env.config.OUTPUT_FOLDER_NAME, 'dir')
                        mkdir(env.config.OUTPUT_FOLDER_NAME);
                    end
                    % 7. 【更新】: 從 config 讀取檔名格式和學號來儲存處理結果
                    output_filename = sprintf(env.config.PROCESSED_IMAGE_FILENAME_FORMAT, env.config.STUDENT_ID, output_labels{i});
                    full_output_path = fullfile(env.config.OUTPUT_FOLDER_NAME, output_filename);
                    imwrite(final_image, full_output_path);
                end
                
                uialert(app.UIFigure, '所有圖片處理完成，並已儲存！', '成功');
        
            catch ME % 錯誤處理
                uialert(app.UIFigure, ['處理過程中發生錯誤: ' ME.message], '錯誤');
            end
        
            % 恢復正常狀態
            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageA_Before_Axes = uiaxes(app.UIFigure);
            title(app.ImageA_Before_Axes, 'Title')
            xlabel(app.ImageA_Before_Axes, 'X')
            ylabel(app.ImageA_Before_Axes, 'Y')
            zlabel(app.ImageA_Before_Axes, 'Z')
            app.ImageA_After_Axes = uiaxes(app.UIFigure);
            title(app.ImageA_After_Axes, 'Title')
            xlabel(app.ImageA_After_Axes, 'X')
            ylabel(app.ImageA_After_Axes, 'Y')
            zlabel(app.ImageA_After_Axes, 'Z')
            app.ImageB_Before_Axes = uiaxes(app.UIFigure);
            title(app.ImageB_Before_Axes, 'Title')
            xlabel(app.ImageB_Before_Axes, 'X')
            ylabel(app.ImageB_Before_Axes, 'Y')
            zlabel(app.ImageB_Before_Axes, 'Z')
            app.ImageB_After_Axes = uiaxes(app.UIFigure);
            title(app.ImageB_After_Axes, 'Title')
            xlabel(app.ImageB_After_Axes, 'X')
            ylabel(app.ImageB_After_Axes, 'Y')
            zlabel(app.ImageB_After_Axes, 'Z')
            app.ImageC_Before_Axes = uiaxes(app.UIFigure);
            title(app.ImageC_Before_Axes, 'Title')
            xlabel(app.ImageC_Before_Axes, 'X')
            ylabel(app.ImageC_Before_Axes, 'Y')
            zlabel(app.ImageC_Before_Axes, 'Z')
            app.ImageC_After_Axes = uiaxes(app.UIFigure);
            title(app.ImageC_After_Axes, 'Title')
            xlabel(app.ImageC_After_Axes, 'X')
            ylabel(app.ImageC_After_Axes, 'Y')
            zlabel(app.ImageC_After_Axes, 'Z')
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.ReportA_TextArea = uitextarea(app.UIFigure);
            app.TextArea_2Label = uilabel(app.UIFigure);
            app.TextArea_2Label.Text = 'Text Area';
            app.ReportB_TextArea = uitextarea(app.UIFigure);
            app.TextArea_3Label = uilabel(app.UIFigure);
            app.TextArea_3Label.Text = 'Text Area';
            app.ReportC_TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end